classdef app0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        PercentageChartTab         matlab.ui.container.Tab
        NotLabel                   matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
        SignalGraphTab             matlab.ui.container.Tab
        NotLabel_2                 matlab.ui.control.Label
        UIAxes_2                   matlab.ui.control.UIAxes
        STFTEnergyDistributionTab  matlab.ui.container.Tab
        NotLabel_3                 matlab.ui.control.Label
        UIAxes_4                   matlab.ui.control.UIAxes
        VisualisationTab           matlab.ui.container.Tab
        UIAxes_3                   matlab.ui.control.UIAxes
        DispOutputLabel            matlab.ui.control.Label
        TextArea                   matlab.ui.control.TextArea
        tahminkismiLabel           matlab.ui.control.Label
        SelectModelDropDown        matlab.ui.control.DropDown
        ClassifyDropDownLabel      matlab.ui.control.Label
        AnalyzeButton              matlab.ui.control.Button
        SellectButton              matlab.ui.control.Button
        SecilenSarkiEditField      matlab.ui.control.EditField
        SelectMusicLabel           matlab.ui.control.Label
    end

%% Yusuf Semih Ciftci 

     properties (Access = public)
        selectedFile % Full path of the selected file
    end

    properties (Access = private)
        typeOfTrainedModelUsed % Stores trained model
        singleVeriSeti % Single_Data_Set variable retrieved from Workspace
    end

    %% ------------------- Loading the model into the system ---------------------------------
    methods (Access = public)
        function startupFcn(app)
            try
                load QuadraticSVM.mat 'trainedModel';
                app.typeOfTrainedModelUsed = trainedModel;
                metin = 'The trained model was loaded with the name typeOfTrainedModelUsed.';
                disp(metin);
                currentText = app.TextArea.Value;
                app.TextArea.Value = [currentText; metin];
            catch ME
                % Inform the user in case of error
                uialert(app.UIFigure, ['An error occurred while loading the model: ', ME.message], 'Error');
            end
        end
    end

    methods (Access = public)
        function loadFineTreeModel(app)
            try
                load FineTree.mat 'FineTree';
                app.typeOfTrainedModelUsed = FineTree;
                metin = 'I loaded the FineTree model with the name typeOfTrainedModelUsed. \n';
                disp(metin);
                currentText = app.TextArea.Value;
                app.TextArea.Value = [currentText; metin];
            catch ME
                % Inform the user in case of error
                uialert(app.UIFigure, ['An error occurred while loading the model: ', ME.message], 'Error');
            end
        end
    end

    methods (Access = public)
        function loadMediumTreeModel(app)
            try
                load MediumTree.mat 'MediumTree';
                app.typeOfTrainedModelUsed = MediumTree;
                metin = 'I loaded the MediumTree model with the name typeOfTrainedModelUsed \n';
                disp(metin);
                currentText = app.TextArea.Value;
                app.TextArea.Value = [currentText; metin];
            catch ME
                % Inform the user in case of error
                uialert(app.UIFigure, ['An error occurred while loading the model: ', ME.message], 'Error');
            end
        end
    end

    methods (Access = public)
        function loadCoarseTreeModel(app)
            try
                load CoarseTree.mat 'CoarseTree';
                app.typeOfTrainedModelUsed = CoarseTree;
                metin = 'I loaded the CoarseTree model with the name typeOfTrainedModelUsed \n';
                disp(metin);
                currentText = app.TextArea.Value;
                app.TextArea.Value = [currentText; metin];
            catch ME
                % Inform the user in case of error
                uialert(app.UIFigure, ['An error occurred while loading the model: ', ME.message], 'Error');
            end
        end
    end

    methods (Access = public)
        function loadLinearSVMModel(app)
            try
                load LinearSVM.mat 'LinearSVM';
                app.typeOfTrainedModelUsed = LinearSVM;
                metin = 'I loaded the LinearSVM model with the name typeOfTrainedModelUsed \n';
                disp(metin);
                currentText = app.TextArea.Value;
                app.TextArea.Value = [currentText; metin];
            catch ME
                % Inform the user in case of error
                uialert(app.UIFigure, ['An error occurred while loading the model: ', ME.message], 'Error');
            end
        end
    end

    methods (Access = public)
        function loadFineKNNModel(app)
            try
                load FineKNN.mat 'FineKNN';
                app.typeOfTrainedModelUsed = FineKNN;
                metin = 'I loaded the FineKNN model with the name typeOfTrainedModelUsed \n';
                disp(metin);
                currentText = app.TextArea.Value;
                app.TextArea.Value = [currentText; metin];
            catch ME
                % Inform the user in case of error
                uialert(app.UIFigure, ['An error occurred while loading the model: FineKNN ', ME.message], 'Error');
            end
        end
    end

    methods (Access = public)
        function loadMediumKNNModel(app)
            try
                load MediumKNN.mat 'MediumKNN';
                app.typeOfTrainedModelUsed = MediumKNN;
                metin = 'I loaded the MediumKNN model with the name typeOfTrainedModelUsed \n';
                disp(metin);
                currentText = app.TextArea.Value;
                app.TextArea.Value = [currentText; metin];
            catch ME
                % Inform the user in case of error
                uialert(app.UIFigure, ['An error occurred while loading the model: ', ME.message], 'Error');
            end
        end
    end

    methods (Access = public)
        function loadCoarseKNNModel(app)
            try
                load CoarseKNN.mat 'CoarseKNN';
                app.typeOfTrainedModelUsed = CoarseKNN;
                metin = 'I loaded the CoarseKNN model with the name typeOfTrainedModelUsed \n';
                disp(metin);
                currentText = app.TextArea.Value;
                app.TextArea.Value = [currentText; metin];
            catch ME
                % Inform the user in case of error
                uialert(app.UIFigure, ['An error occurred while loading the model: ', ME.message], 'Error');
            end
        end
    end
    
    methods (Access = public)
        function loadBoostedTreesModel(app)
            try
                load BoostedTrees.mat 'BoostedTrees';
                app.typeOfTrainedModelUsed = BoostedTrees;
                metin = ' I loaded the BoostedTrees model with the name typeOfTrainedModelUsed \n';
                disp(metin);
                currentText = app.TextArea.Value;
                app.TextArea.Value = [currentText; metin];
            catch ME
                % Inform the user in case of error
                uialert(app.UIFigure, ['An error occurred while loading the model: ', ME.message], 'Error');
            end
        end
    end

    methods (Access = public)
        function loadNarrowNeuralNetworkModel(app)
            try
                load NarrowNeuralNetwork.mat 'NarrowNeuralNetwork';
                app.typeOfTrainedModelUsed = NarrowNeuralNetwork;
                metin = ' I loaded the NarrowNeuralNetwork model with the name typeOfTrainedModelUsed \n';
                disp(metin);
                currentText = app.TextArea.Value;
                app.TextArea.Value = [currentText; metin];
            catch ME
                % Inform the user in case of error
                uialert(app.UIFigure, ['An error occurred while loading the model: ', ME.message], 'Error');
            end
        end
    end

    methods (Access = public)
        function loadMediumNeuralNetworkModel(app)
            try
                load MediumNeuralNetwork.mat 'MediumNeuralNetwork';
                app.typeOfTrainedModelUsed = MediumNeuralNetwork;
                metin = 'MediumNeuralNetwork modeli typeOfTrainedModelUsed ismi ile yükledim. Metod kısmında \n';
                disp(metin);
                currentText = app.TextArea.Value;
                app.TextArea.Value = [currentText; metin];
            catch ME
                % Inform the user in case of error
                uialert(app.UIFigure, ['An error occurred while loading the model: ', ME.message], 'Error');
            end
        end
    end

    %% -------------------------- model loading last  -------------------------------------------------
    methods (Access =private)
        % Function to calculate and plot STFT on app.UIAxes_4
        function plotSTFTOnUIAxes(app, y, Fs)
            try
                % Parameters for STFT
                win = hamming(128);         % Window function (Hamming window of size 128)
                overlap = 120;              % Overlap between windows
                nfft = 256;                 % Number of FFT points
                
                % Compute the Short-Time Fourier Transform
                [S, F, T] = spectrogram(y, win, overlap, nfft, Fs);
                
                % Calculate the energy levels for each frequency
                energy = abs(S);            % Magnitude of the STFT (energy levels)
                
                % Plotting the energy distribution on app.UIAxes_4
                plot(app.UIAxes_4, F, mean(energy, 2), 'LineWidth', 1.5); % Mean energy across time
                app.UIAxes_4.XLabel.String = 'Frequency (Hz)';  % Set X-axis label
                app.UIAxes_4.YLabel.String = 'Average Energy';  % Set Y-axis label
                app.UIAxes_4.Title.String = 'STFT Energy Distribution'; % Set title
                grid(app.UIAxes_4, 'on');  % Enable grid for better readability
                
            catch ME
                % Handle errors during plotting
                uialert(app.UIFigure, ['Error while plotting STFT: ', ME.message], 'Error');
            end
        end
    end

    methods (Access = private)
        
        function single_feature_extraction_and_train(app, outputFileName)
            try
                % 1. Check the selected audio file
                if isempty(app.selectedFile)
                    uialert(app.UIFigure, 'Please select an audio file first.', 'Error');
                    return;
                end
                
                % 2. Read the selected file
                [y, Fs] = audioread(app.selectedFile); 
                disp(['The audio file was read successfully: ', app.selectedFile]);
        
                % 3. Calculate spectrogram and extract features
                y1 = abs(spectrogram(y, kaiser(2048, 128), 120, 16, 1E3, 'yaxis'));
                y1 = y1(:);
                audioTable=[];
        
                % 4. Size control
                if length(y1) >= 3087
                    y1 = y1(1:3087); 
                else
                    uialert(app.UIFigure, 'Insufficient spectrogram size of the audio file.', 'Error');
                    return;
                end
        
                % 5. Save properties to a variable
                single_Veri_Seti = y1;
                audioTable=[audioTable y1];


                save(outputFileName, 'single_Veri_Seti');
                disp(['Feature extraction is complete. Data set "', outputFileName]);
                
                %%  % Pedicting genre
                try
                    [pred, scores] = app.typeOfTrainedModelUsed.predictFcn(audioTable);                 
                    % Converting numbers to genres and setting the suggestions array
                    switch pred 
                        case 1
                            genre = 'Blues';
                        case 2
                            genre = 'Classical';
                        case 3
                            genre = 'Country';
                        case 4
                            genre = 'Disco';
                        case 5
                            genre = 'Hiphop';
                    end
                    disp(genre);
                    currentText = app.TextArea.Value;
                    app.TextArea.Value = [currentText; genre];                    
                    app.tahminkismiLabel.Text = sprintf('The type of music you have selected: %s.', genre);
                    disp('Prediction Scores:');
                    disp(scores);                 

                    % Normalise scores (Softmax method)
                    exp_scores = exp(scores); 
                    percent_scores = exp_scores / sum(exp_scores); 
                    
                    % Species labels
                    labels = {'Blues', 'Classical', 'Country', 'Disco', 'Hiphop'};
                    
                    % Rod colours
                    colors = [
                        70, 130, 180;   
                        255, 215, 0;    
                        50, 205, 50;    
                        147, 112, 219;  
                        255, 140, 0     
                    ] / 255; 
                    
                    % Create bar graph
                    hBar = bar(app.UIAxes, percent_scores * 100); 
                    hBar.FaceColor = 'flat'; 
                    
                    % Assign stick colours
                    for i = 1:length(percent_scores)
                        hBar.CData(i, :) = colors(i, :);
                    end
                    
                    % Axis settings
                    app.UIAxes.XTick = 1:5; %  Positions on the X axis
                    app.UIAxes.XTickLabel = labels; % Add species labels to the X-axis
                    app.UIAxes.Title.String = 'Music Genre Prediction Percentages';
                    app.UIAxes.XLabel.String = 'Music Genre';
                    app.UIAxes.YLabel.String = 'Forecast Percentage (%)';
                    grid(app.UIAxes, 'on'); % add Grid

                    % 8. Signal graph (UIAxes_2)
                    try
                        t = (0:length(y)-1) / Fs; % Time vector
                        plot(app.UIAxes_2, t, y, 'b');
                        app.UIAxes_2.Title.String = 'Audio Signalling';
                        app.UIAxes_2.XLabel.String = 'Time (s)';
                        app.UIAxes_2.YLabel.String = 'Amplitude';
                        grid(app.UIAxes_2, 'on');
                    catch ME
                        uialert(app.UIFigure, ['Error when plotting the signal graph: ', ME.message], 'Error');
                    end

                    try
                        % Spectrogram on UIAxes_3
                        axes(app.UIAxes_3); 
                        spectrogram(y, kaiser(256, 5), 128, 512, Fs, 'yaxis'); 
                        
                        % Grafik düzenlemeleri
                        colormap(app.UIAxes_3, 'parula'); 
                        app.UIAxes_3.Title.String = 'Spektrogram'; 
                        app.UIAxes_3.XLabel.String = 'Time (s)'; 
                        app.UIAxes_3.YLabel.String = 'Frekans (kHz)'; 
                        colorbar(app.UIAxes_3);
                    catch ME
                        % Inform the user in case of error
                        uialert(app.UIFigure, ['Error drawing the signal visualisation: ', ME.message], 'Error');
                    end
                    try
                        plotSTFTOnUIAxes(app, y, Fs);
                    catch
                        uialert(app.UIFigure, ['Yeni eklediğin grafik hatalı ', ME.message], 'Error');
                    end
                 

                 catch ME
                    % Inform the user in case of error
                    uialert(app.UIFigure, ['in the estimation process ', ME.message], 'Error');
                end
        
            catch ME
                % Inform the user in case of error
                uialert(app.UIFigure, ['An error has occurred: ', ME.message], 'Error');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SellectButton
        function SellectButtonPushed(app, event)
            % Ask the user to select a .wav file
            [fileName, filePath] = uigetfile('*.wav', 'Select a WAV File');
            
            % Check if the file is selected
            if isequal(fileName, 0)
                % User did not select file
                uialert(app.UIFigure, 'User did not select a file.', 'Error');
            else
                % Merge file path and show in EditField
                fullFileName = fullfile(filePath, fileName);
                app.selectedFile = fullFileName; 
                app.SecilenSarkiEditField.Value = fileName;
                
                try
                    audioread(fullFileName);
                    disp(['Selected file: ', fullFileName]);
                catch ME
                    uialert(app.UIFigure, ['Audio file could not be read: ', ME.message], 'Error');
                    return;
                end
            end
            startupFcn(app);
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            % 1. Specify output file
            outputFileName = 'TekSarkiVeriSeti.mat';

            % 2. Call feature extraction
            single_feature_extraction_and_train(app, outputFileName);

            % 3. TekSarkiVeriSeti.mat file and drop it into the workspace
            try
                if isfile(outputFileName)                    
                    evalin('base', sprintf('load(''%s'')', outputFileName));
                    disp('TekSarkiVeriSeti.mat successfully loaded into the workspace.');
                    
                    currentText = app.TextArea.Value;
                    metin = 'TekSarkiVeriSeti.mat file uploaded to workspace!';
                    app.TextArea.Value = [currentText; metin];
                else
                    error('TekSarkiVeriSeti.mat file not found.');
                end
            catch ME
                % Inform the user in case of error
                uialert(app.UIFigure, ['An error occurred: ', ME.message], 'Error');
            end
        end

        % Value changed function: SelectModelDropDown
        function SelectModelDropDownValueChanged(app, event)
            % Get the new selected value
            selectedModel = event.Value;
            disp(['Selected Model: ', selectedModel]);
        
            % Process according to model selection
            try
                switch selectedModel
                    case 'Fine Tree'                       
                        loadFineTreeModel(app);
                        disp('FineTree loaded.');
                        
                    case 'Quadratic SVM'
                        startupFcn(app);
                        disp('Quadratic SVM loaded.');
        
                    case 'Medium Tree'
                        loadMediumTreeModel(app);
                        disp('Medium Tree loaded.');
        
                    case 'Coarse Tree'
                        loadCoarseTreeModel(app);
                        disp('Coarse Tree loaded.');
                    
                    case 'Linear SVM'
                        loadLinearSVMModel(app);
                        disp('Lienar SVM loaded');

                    case 'Fine KNN'
                        loadFineKNNModel(app);
                        disp('Fine KNN loaded');

                    case 'Medium KNN'
                        loadMediumKNNModel(app);
                        disp('Medium KNN loaded');
                    
                    case 'Coarse KNN'
                        loadCoarseKNNModel(app);
                        disp('Coarse KNN loaded');

                     case 'Boosted Trees'
                        loadBoostedTreesModel(app);
                        disp('Boosted Trees loaded');
                      
                     case 'Narrow Neural Network'
                        loadNarrowNeuralNetworkModel(app);
                        disp('Narrow Neural Networks loaded');

                     case 'Medium Neural Network'
                        loadMediumNeuralNetworkModel(app);
                        disp('Medium Neural Network loaded');
                    
        
                    otherwise
                        uialert(app.UIFigure, 'Invalid model selection.', 'Error');
                end
            catch ME
                uialert(app.UIFigure, ['An error has occurred: ', ME.message], 'Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectMusicLabel = uilabel(app.UIFigure);
            app.SelectMusicLabel.Text = 'Select Music';
            app.SecilenSarkiEditField = uieditfield(app.UIFigure, 'text');
            app.SellectButton = uibutton(app.UIFigure, 'push');
            app.SellectButton.ButtonPushedFcn = createCallbackFcn(app, @SellectButtonPushed, true);
            app.SellectButton.Text = 'Select';
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.ClassifyDropDownLabel = uilabel(app.UIFigure);
            app.ClassifyDropDownLabel.Text = 'Classify';
            app.SelectModelDropDown = uidropdown(app.UIFigure);
            app.SelectModelDropDown.Items = {'Quadratic SVM', 'Fine Tree', 'Medium Tree', 'Coarse Tree', 'Linear SVM', 'Fine KNN', 'Medium KNN', 'Coarse KNN', 'Boosted Trees', 'Narrow Neural Network', 'Medium Neural Network'};
            app.SelectModelDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectModelDropDownValueChanged, true);
            app.SelectModelDropDown.Value = 'Quadratic SVM';
            app.tahminkismiLabel = uilabel(app.UIFigure);
            app.tahminkismiLabel.Text = 'Forecast Section:';
            app.TextArea = uitextarea(app.UIFigure);
            app.DispOutputLabel = uilabel(app.UIFigure);
            app.DispOutputLabel.Text = 'Disp Output:';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PercentageChartTab = uitab(app.TabGroup);
            app.PercentageChartTab.Title = 'Percentage Chart';
            app.UIAxes = uiaxes(app.PercentageChartTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NotLabel = uilabel(app.PercentageChartTab);
            app.NotLabel.Text = 'Note: The table above shows the percentage similarity of the analysed sound to each musical genre.';
            app.SignalGraphTab = uitab(app.TabGroup);
            app.SignalGraphTab.Title = 'Signal Graph';
            app.UIAxes_2 = uiaxes(app.SignalGraphTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.NotLabel_2 = uilabel(app.SignalGraphTab);
            app.NotLabel_2.Text = 'Note: The table above is the analysed sound converted into a signal graph.';
            app.STFTEnergyDistributionTab = uitab(app.TabGroup);
            app.STFTEnergyDistributionTab.Title = 'STFT Energy Distribution';
            app.UIAxes_4 = uiaxes(app.STFTEnergyDistributionTab);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.NotLabel_3 = uilabel(app.STFTEnergyDistributionTab);
            app.NotLabel_3.Text = 'It shows how the frequency components of the sound signal vary, helping identify key characteristics of the audio, such as changes in pitch or timbre.';
            app.VisualisationTab = uitab(app.TabGroup);
            app.VisualisationTab.Title = 'Visualisation';
            app.UIAxes_3 = uiaxes(app.VisualisationTab);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end